classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        EmergencyStopButton        matlab.ui.control.Button
        TeachfunctionforWidowX250Button  matlab.ui.control.Button
        MakeLoadedUR3eMoveButton   matlab.ui.control.Button
        MakeLoadedWidowMoveButton  matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelWidow % Where I will put the loaded Widow
        modelUR3e % Where I will put the loaded UR3e
        qz = [0     0     0     0     0     0];

        qrWidow = deg2rad([-15, 42.3, -5.9, -6.17, 52.4, -50.3]);
        qrUR3e = deg2rad([0, -20, 40, -110, -90, 0]);

        Widowfin1 = deg2rad([-180, -14.6, 26.9, 2.18, 76.3, -24.4]);
        UR3efin2 = deg2rad([-180, -50, 90, -130, -90, 0]);

        isPaused = false;

    end
    
    methods (Access = private)
        
        function pauseOperation(app)
            % Set the app for Paused state
            setappdata(app.figure1, 'Paused', true);
            while getappdata(app.figure1, 'Paused')
                pause(1);  % delay
            end
        end
        
        function resumeOperation(app)
            % Set the app for Pause state
             setappdata(app.figure1, 'Paused', false);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.modelWidow = WidowX250;
            app.modelWidow.model.base = transl([0.5,0,0]);
            app.modelWidow.model.plot(app.qrWidow, 'workspace', [-2 2 -2 2 -0.5 2]);
            hold on;         

            app.modelUR3e = UR3e;
            app.modelUR3e.model.base = transl([-0.5,0,0]);
            app.modelUR3e.model.animate(app.qrUR3e);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.MakeLoadedWidowMoveButton.Enable = true;
            app.MakeLoadedUR3eMoveButton.Enable = true;
            
        end

        % Button pushed function: MakeLoadedWidowMoveButton
        function MakeLoadedWidowMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrWidow, app.Widowfin1, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelWidow.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: MakeLoadedUR3eMoveButton
        function MakeLoadedUR3eMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR3e, app.UR3efin2, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR3e.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: TeachfunctionforWidowX250Button
        function TeachfunctionforWidowX250ButtonPushed(app, event)
            % Prompt user to choose between joint and Cartesian movements
            movementType = questdlg('Select movement type:', ...
                'Teach Function', ...
                'Joint Movement', 'Cartesian Movement', 'Cancel', 'Joint Movement');

            switch movementType
                case 'Joint Movement'
                    % q = app.modelWidow.model.getpos();
                    % app.modelWidow.model.teach(q);
                    x = 1
                    f = figure;
                    while x == 1
                        r = WidowX250;
                        r.model.teach;
                        hold on
                        % x = 0;
                    end

                case 'Cartesian Movement'

                case 'Cancel'

            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            if app.isPaused
                app.isPaused = false;
                % Resume the operation
                resumeOperation(app);
            else
                app.isPaused = true;
                % Pause the operation
                pauseOperation(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.MakeLoadedWidowMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedWidowMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedWidowMoveButtonPushed, true);
            app.MakeLoadedWidowMoveButton.Text = 'Make Loaded Widow Move';
            app.MakeLoadedUR3eMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedUR3eMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR3eMoveButtonPushed, true);
            app.MakeLoadedUR3eMoveButton.Text = 'Make Loaded UR3e Move';
            app.TeachfunctionforWidowX250Button = uibutton(app.figure1, 'push');
            app.TeachfunctionforWidowX250Button.ButtonPushedFcn = createCallbackFcn(app, @TeachfunctionforWidowX250ButtonPushed, true);
            app.TeachfunctionforWidowX250Button.Text = 'Teach function for WidowX250';
            app.EmergencyStopButton = uibutton(app.figure1, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end